classdef game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonopolyInMatlabUIFigure       matlab.ui.Figure
        Player4Label                   matlab.ui.control.Label
        Player3Label                   matlab.ui.control.Label
        EndTurnButton                  matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        YouvegonebankruptLabel         matlab.ui.control.Label
        UseGetoutofjailfreecardButton  matlab.ui.control.Button
        OptionsLabel                   matlab.ui.control.Label
        Player2Label                   matlab.ui.control.Label
        Player1Label                   matlab.ui.control.Label
        CurrentSpotOptionsLabel        matlab.ui.control.Label
        Image3_4                       matlab.ui.control.Image
        Image3_3                       matlab.ui.control.Image
        Image3_2                       matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        BuyahouseButton                matlab.ui.control.Button
        BuyahotelButton                matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        BuyPropertyHereButton          matlab.ui.control.Button
        SellAPropertyButton            matlab.ui.control.Button
        LeavegameButton                matlab.ui.control.Button
        StartGameButton                matlab.ui.control.Button
        HostOptionsLabel               matlab.ui.control.Label
        CurrentTurnWillowCatLabel      matlab.ui.control.Label
        YourPropertiesReadingRailroadMorgagedLabel  matlab.ui.control.Label
        YourFunds100Label              matlab.ui.control.Label
        PlayersConnectedLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        TextArea                       matlab.ui.control.TextArea
        ChatEditField                  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        GameInstance % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roomId, userIsHost, localPlayerId)
            import Game.*;
            import StompClient.*;
            
            app.GameInstance = Game.gameInstance(app, roomId, userIsHost, localPlayerId);
            app.GameInstance.setStompClient(StompClient.getInstance());
        end

        % Button pushed function: BuyahouseButton
        function BuyahouseButtonPushed(app, event)
            if(app.Panel.Visible == "on")
                app.Panel.Visible = "off";
            else
                app.Panel.Visible = "on";
            end
        end

        % Button pushed function: LeavegameButton
        function LeavegameButtonPushed(app, event)
            delete(app);
            main_menu();
        end

        % Window key press function: MonopolyInMatlabUIFigure
        function MonopolyInMatlabUIFigureWindowKeyPress(app, event)

            if(strcmp(event.Key, "return"))
                focusedComponent = app.MonopolyInMatlabUIFigure.CurrentObject;

                if((focusedComponent == app.ChatEditField) && ~isempty(app.ChatEditField.Value))
                    app.GameInstance.sendChatMessage(app.ChatEditField.Value);
                    app.ChatEditField.Value = "";
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonopolyInMatlabUIFigure = uifigure('Visible', 'off');
            app.MonopolyInMatlabUIFigure.Name = 'Monopoly In Matlab';
            app.MonopolyInMatlabUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @MonopolyInMatlabUIFigureWindowKeyPress, true);
            app.ChatEditField = uieditfield(app.MonopolyInMatlabUIFigure, 'text');
            app.ChatEditField.Placeholder = 'Enter message';
            app.TextArea = uitextarea(app.MonopolyInMatlabUIFigure);
            app.TextArea.Editable = 'off';
            app.Image = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image.ImageSource = 'monopoly_board.jpg';
            app.PlayersConnectedLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.PlayersConnectedLabel.Text = 'Players Connected(N/A): ';
            app.YourFunds100Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.YourFunds100Label.Text = 'Your Funds: $100';
            app.YourPropertiesReadingRailroadMorgagedLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.YourPropertiesReadingRailroadMorgagedLabel.Text = {'Your Properties:'; 'Reading Railroad(Morgaged)'};
            app.CurrentTurnWillowCatLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.CurrentTurnWillowCatLabel.Text = 'Current Turn: WillowCat';
            app.HostOptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.HostOptionsLabel.Text = 'Host Options';
            app.StartGameButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.StartGameButton.Text = 'Start Game';
            app.LeavegameButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.LeavegameButton.ButtonPushedFcn = createCallbackFcn(app, @LeavegameButtonPushed, true);
            app.LeavegameButton.Text = 'Leave game';
            app.SellAPropertyButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.SellAPropertyButton.Text = 'Sell A Property';
            app.BuyPropertyHereButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyPropertyHereButton.Text = 'Buy Property Here';
            app.Image2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image2.ImageSource = 'hotel.png';
            app.BuyahotelButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyahotelButton.Text = 'Buy a hotel';
            app.BuyahouseButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyahouseButton.ButtonPushedFcn = createCallbackFcn(app, @BuyahouseButtonPushed, true);
            app.BuyahouseButton.Text = 'Buy a house';
            app.Image3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3.ImageSource = 'house.png';
            app.Image3_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_2.ImageSource = 'house.png';
            app.Image3_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_3.ImageSource = 'house.png';
            app.Image3_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_4.ImageSource = 'house.png';
            app.CurrentSpotOptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.CurrentSpotOptionsLabel.Text = 'Current Spot Options';
            app.Player1Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player1Label.Text = '';
            app.Player2Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player2Label.Text = '';
            app.OptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.OptionsLabel.Text = 'Options';
            app.UseGetoutofjailfreecardButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.UseGetoutofjailfreecardButton.Text = 'Use Get out of jail free card';
            app.Panel = uipanel(app.MonopolyInMatlabUIFigure);
            app.Panel.Visible = 'off';
            app.YouvegonebankruptLabel = uilabel(app.Panel);
            app.YouvegonebankruptLabel.Text = 'Youve gone bankrupt!';
            app.EndTurnButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.EndTurnButton.Text = 'End Turn';
            app.Player3Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player3Label.Text = '';
            app.Player4Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player4Label.Text = '';
            % Show the figure after all components are created
            app.MonopolyInMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonopolyInMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonopolyInMatlabUIFigure)
        end
    end
end